classdef Interfaz_Grafica_Lab_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        SeriesdeFourierLabel           matlab.ui.control.Label
        DropDownLabel                  matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        AmplitudEditFieldLabel         matlab.ui.control.Label
        AmplitudEditField              matlab.ui.control.NumericEditField
        NumerodearmonicosEditFieldLabel  matlab.ui.control.Label
        NumerodearmonicosEditField     matlab.ui.control.NumericEditField
        TransformadadeFourierLabel     matlab.ui.control.Label
        FrecuenciadeMEditFieldLabel    matlab.ui.control.Label
        FrecuenciadeMEditField         matlab.ui.control.NumericEditField
        AmplitudEditField_2Label       matlab.ui.control.Label
        AmplitudEditField_2            matlab.ui.control.NumericEditField
        NumerodeMuestraEditFieldLabel  matlab.ui.control.Label
        NumerodeMuestraEditField       matlab.ui.control.NumericEditField
        Button                         matlab.ui.control.Button
        PeriodoEditFieldLabel          matlab.ui.control.Label
        PeriodoEditField               matlab.ui.control.NumericEditField
        FrecuenciaEditFieldLabel       matlab.ui.control.Label
        FrecuenciaEditField            matlab.ui.control.NumericEditField
        Button2                        matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            A=app.AmplitudEditField.Value;
            T=app.PeriodoEditField.Value;
            n=app.NumerodearmonicosEditField.Value;
            switch app.DropDown.Value
                case 'Rectangular periódica'
                    f=1/T;
                    dt=0.001;
                    t=0:dt:1;
                    x=A*square(2*pi*f*t);
                    title(app.UIAxes,'Señal Rectangular');
                    ylabel(app.UIAxes,'Amplitud');
                    xlabel(app.UIAxes,'tiempo (t)');
                    plot(app.UIAxes,t,x);
                    w0=2*pi/T;
                    ak = zeros(n,1);
                    bk = zeros(n,1);
                    [r, m] = size(t);
                    A0 = 0;
                    for i=1:m
                        A0=A0+(1/T)*x(i)*dt;
                    end
                    for i=1:n
                        for j=1:m
                            ak(i)=ak(i)+((2/T)*x(j)*cos(i*t(j)*w0))*dt;
                            bk(i)=bk(i)+((2/T)*x(j)*sin(i*t(j)*w0))*dt;
                        end
                    end
                    xf=0*t+A0;
                    as=ak/10;
                    bs=bk/10;
                    for i=1:1:n
                        xf=xf+as(i)*cos(i*w0*t)+bs(i)*sin(i*w0*t);
                    end
                    plot(app.UIAxes2,t,xf);
                    title(app.UIAxes2,'Reconstruccion Series de Fourier');
                    ylabel(app.UIAxes2,'Amplitud');
                    xlabel(app.UIAxes2,'Tiempo (t)');
                case 'Triangular'
                    f=1/T;
                    dt=0.001;
                    t=0:dt:1;
                    x=A*sawtooth(2*pi*f*t);
                    title(app.UIAxes,'Señal Triangular');
                    ylabel(app.UIAxes,'Amplitud');
                    xlabel(app.UIAxes,'tiempo (t)');
                    plot(app.UIAxes,t,x);
                    w0=2*pi/T;
                    ak = zeros(n,1);
                    bk = zeros(n,1);
                    [r, m] = size(t);
                    A0 = 0;
                    for i=1:m
                        A0=A0+(1/T)*x(i)*dt;
                    end
                    for i=1:n
                        for j=1:m
                            ak(i)=ak(i)+((2/T)*x(j)*cos(i*t(j)*w0))*dt;
                            bk(i)=bk(i)+((2/T)*x(j)*sin(i*t(j)*w0))*dt;
                        end
                    end
                    xf=0*t+A0;
                    as=ak/10;
                    bs=bk/10;
                    for i=1:1:n
                        xf=xf+as(i)*cos(i*w0*t)+bs(i)*sin(i*w0*t);
                    end
                    plot(app.UIAxes2,t,xf);
                    title(app.UIAxes2,'Reconstruccion Series de Fourier');
                    ylabel(app.UIAxes2,'Amplitud');
                    xlabel(app.UIAxes2,'Tiempo (t)');
                case 'Exponencial'
                    f=1/T;
                    dt=0.01;
                    t = -0.25:dt:0.25;
                    x= A*exp(f*t);
                    title(app.UIAxes,'Señal Exponencial');
                    ylabel(app.UIAxes,'Amplitud');
                    xlabel(app.UIAxes,'tiempo (t)');
                    plot(app.UIAxes,t,x);
                    w0=2*pi/T;
                    ak = zeros(n,1);
                    bk = zeros(n,1);
                    [r, m] = size(t);
                    A0 = 0;
                    for i=1:m
                        A0=A0+(1/T)*x(i)*dt;
                    end
                    for i=1:n
                        for j=1:m
                            ak(i)=ak(i)+((2/T)*x(j)*cos(i*t(j)*w0))*dt;
                            bk(i)=bk(i)+((2/T)*x(j)*sin(i*t(j)*w0))*dt;
                        end
                    end
                    xf=0*t+A0;
                    as=ak/10;
                    bs=bk/10;
                    for i=1:1:n
                        xf=xf+as(i)*cos(i*w0*t)+bs(i)*sin(i*w0*t);
                    end
                    plot(app.UIAxes2,t,xf);
                    title(app.UIAxes2,'Reconstruccion Series de Fourier');
                    ylabel(app.UIAxes2,'Amplitud');
                    xlabel(app.UIAxes2,'Tiempo (t)');
                case 'Sinusoidal rectificada'
                    f=1/T;
                    T2=4/f;
                    dt=1/(4*2*pi*f);
                    t = 0:dt:T2;
                    y = A*sin(2*pi*f*t);
                    x= abs(y);
                    title(app.UIAxes,'Sinusoidal rectificada');
                    ylabel(app.UIAxes,'Amplitud');
                    xlabel(app.UIAxes,'tiempo (t)');
                    plot(app.UIAxes,t,x);
                    w0=2*pi/T;
                    ak = zeros(n,1);
                    bk = zeros(n,1);
                    [r, m] = size(t);
                    A0 = 0;
                    for i=1:m
                        A0=A0+(1/T)*x(i)*dt;
                    end
                    for i=1:n
                        for j=1:m
                            ak(i)=ak(i)+((2/T)*x(j)*cos(i*t(j)*w0))*dt;
                            bk(i)=bk(i)+((2/T)*x(j)*sin(i*t(j)*w0))*dt;
                        end
                    end
                    xf=0*t+A0;
                    as=ak/20;
                    bs=bk/20;
                    for i=1:1:n
                        xf=xf+as(i)*cos(i*w0*t)+bs(i)*sin(i*w0*t);
                    end
                    plot(app.UIAxes2,t,xf);
                    title(app.UIAxes2,'Reconstruccion Series de Fourier');
                    ylabel(app.UIAxes2,'Amplitud');
                    xlabel(app.UIAxes2,'Tiempo (t)');
            end
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            A=app.AmplitudEditField_2.Value;
            fs=app.FrecuenciadeMEditField.Value;
            f=app.FrecuenciaEditField.Value;
            N=app.NumerodeMuestraEditField.Value;
            switch app.DropDown.Value
                case 'Rectangular periódica'
                    n=0:N-1;
                    x1= A*square(2*pi*f*n/fs);
                    y=fft(x1);
                    sp_fft=abs(fftshift(y));
                    f1=(-0.5:1/length(sp_fft):0.5-(1/length(sp_fft)))*fs;
                    stem(app.UIAxes,n,x1);
                    title(app.UIAxes,'Señal Rectangular');
                    ylabel(app.UIAxes,'Amplitud');
                    xlabel(app.UIAxes,'tiempo (t)');
                    stem(app.UIAxes2,f1,sp_fft);
                    title(app.UIAxes2,'Representacion transformada de Fourier');
                    ylabel(app.UIAxes2,'');
                    xlabel(app.UIAxes2,'');
                case 'Triangular'
                    n=0:N-1;
                    x1= A*sawtooth(2*pi*f*n/fs);
                    y=fft(x1);
                    sp_fft=abs(fftshift(y));
                    f1=(-0.5:1/length(sp_fft):0.5-(1/length(sp_fft)))*fs;
                    stem(app.UIAxes,n,x1);
                    title(app.UIAxes,'Señal Triangular');
                    ylabel(app.UIAxes,'Amplitud');
                    xlabel(app.UIAxes,'tiempo (t)');
                    stem(app.UIAxes2,f1,sp_fft);
                    title(app.UIAxes2,'Representacion transformada de Fourier');
                    ylabel(app.UIAxes2,'');
                    xlabel(app.UIAxes2,'');
                case 'Exponencial'
                    n=0:N-1;
                    x1= A*exp(f*n/fs);
                    y=fft(x1);
                    sp_fft=abs(fftshift(y));
                    f1=(-0.5:1/length(sp_fft):0.5-(1/length(sp_fft)))*fs;
                    stem(app.UIAxes,n,x1);
                    title(app.UIAxes,'Señal Exponencial');
                    ylabel(app.UIAxes,'Amplitud');
                    xlabel(app.UIAxes,'tiempo (t)');
                    stem(app.UIAxes2,f1,sp_fft);
                    title(app.UIAxes2,'Representacion transformada de Fourier');
                    ylabel(app.UIAxes2,'');
                    xlabel(app.UIAxes2,'');
                case 'Sinusoidal rectificada'
                    n=0:N-1;
                    x=A*sin(2*pi*f*n/fs);
                    x1= abs(x);
                    y=fft(x1);
                    sp_fft=abs(fftshift(y));
                    f1=(-0.5:1/length(sp_fft):0.5-(1/length(sp_fft)))*fs;
                    stem(app.UIAxes,n,x1);
                    title(app.UIAxes,'Señal Sinusoidal rectificada');
                    ylabel(app.UIAxes,'Amplitud');
                    xlabel(app.UIAxes,'tiempo (t)');
                    stem(app.UIAxes2,f1,sp_fft);
                    title(app.UIAxes2,'Representacion transformada de Fourier');
                    ylabel(app.UIAxes2,'');
                    xlabel(app.UIAxes2,'');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.SeriesdeFourierLabel = uilabel(app.UIFigure);
            app.SeriesdeFourierLabel.Text = 'Series de Fourier ';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Rectangular periódica', 'Triangular', 'Exponencial', 'Sinusoidal rectificada'};
            app.DropDown.Value = 'Rectangular periódica';
            app.AmplitudEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumerodearmonicosEditFieldLabel = uilabel(app.UIFigure);
            app.NumerodearmonicosEditFieldLabel.Text = 'Numero de armonicos';
            app.NumerodearmonicosEditField = uieditfield(app.UIFigure, 'numeric');
            app.TransformadadeFourierLabel = uilabel(app.UIFigure);
            app.TransformadadeFourierLabel.Text = 'Transformada de Fourier';
            app.FrecuenciadeMEditFieldLabel = uilabel(app.UIFigure);
            app.FrecuenciadeMEditFieldLabel.Text = 'Frecuencia de M';
            app.FrecuenciadeMEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudEditField_2Label = uilabel(app.UIFigure);
            app.AmplitudEditField_2Label.Text = 'Amplitud';
            app.AmplitudEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.NumerodeMuestraEditFieldLabel = uilabel(app.UIFigure);
            app.NumerodeMuestraEditFieldLabel.Text = 'Numero de Muestra';
            app.NumerodeMuestraEditField = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.PeriodoEditFieldLabel = uilabel(app.UIFigure);
            app.PeriodoEditFieldLabel.Text = 'Periodo';
            app.PeriodoEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrecuenciaEditFieldLabel = uilabel(app.UIFigure);
            app.FrecuenciaEditFieldLabel.Text = 'Frecuencia';
            app.FrecuenciaEditField = uieditfield(app.UIFigure, 'numeric');
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = 'Button2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz_Grafica_Lab_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end